classdef GUI_CrackDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ImageAxes                    matlab.ui.control.UIAxes
        LoadButton                   matlab.ui.control.Button
        RandomImageButton            matlab.ui.control.Button
        CrackDetectionTextAreaLabel  matlab.ui.control.Label
        CrackDetectionTextArea       matlab.ui.control.TextArea
        ImageAxes_2                  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            

                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            

           
                    % Display the original image
                    inputSize=[224 224];
                    im = imresize(im, inputSize);
                    imagesc(app.ImageAxes,im);
                    
                    % Plot all histograms with the same data for grayscale
                    load model1.mat;
                    pred = classify (wallnet, im);
                    if string(pred) == "Positive"
                        load resnet18SegModel.mat;
                        C = semanticseg(im, net);
                        C = C == 'Crack';
                        B = labeloverlay(im,C);
                        imagesc(app.ImageAxes_2,B);
                        value = "Crack is detected. " + ...
                            " Crack Area is highlighted with Blue colour.";
                    else
                        value = "There is no Crack.";
                        imagesc(app.ImageAxes_2,im);
                    end
                    app.CrackDetectionTextArea.Value = value;
                        
         
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes_2.Visible = 'off';
            axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
            updateimage(app, 'Test\Positive\00122.jpg');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end

        % Value changed function: CrackDetectionTextArea
        function Crack_label(app, event)


        end

        % Button pushed function: RandomImageButton
        function Random_img(app, event)
           fileList = dir(fullfile('Test\Positive', '/*.jpg'));
           randomIndex = randi(length(fileList), 1, 1); % Get random number.
           fullFileName = fullfile('Test\Positive', fileList(randomIndex).name);
         
           
           updateimage(app, fullFileName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'CrackTect System';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.RandomImageButton = uibutton(app.UIFigure, 'push');
            app.RandomImageButton.ButtonPushedFcn = createCallbackFcn(app, @Random_img, true);
            app.RandomImageButton.Text = 'Random Image';
            app.CrackDetectionTextAreaLabel = uilabel(app.UIFigure);
            app.CrackDetectionTextAreaLabel.Text = 'Crack Detection';
            app.CrackDetectionTextArea = uitextarea(app.UIFigure);
            app.CrackDetectionTextArea.ValueChangedFcn = createCallbackFcn(app, @Crack_label, true);
            app.CrackDetectionTextArea.Editable = 'off';
            app.ImageAxes_2 = uiaxes(app.UIFigure);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.XTickLabel = {'[ ]'};
            app.ImageAxes_2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_CrackDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end